classdef NetworkGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AdvancedOptionsPanel            matlab.ui.container.Panel
        LogWindowTextArea               matlab.ui.control.TextArea
        LogWindowTextAreaLabel          matlab.ui.control.Label
        NetworkStatisticsPanel          matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        GeneralSettingsPanel            matlab.ui.container.Panel
        TableFileNameEditField          matlab.ui.control.EditField
        TableFileNameEditFieldLabel     matlab.ui.control.Label
        DataFileNameEditField           matlab.ui.control.EditField
        DataFileNameEditFieldLabel      matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        SaveNetworksCheckBox            matlab.ui.control.CheckBox
        SelectFolderButton              matlab.ui.control.Button
        AdvancedOptionsLabel            matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        NetworkSeedLabel                matlab.ui.control.Label
        ShowNetworkStatisticsCheckBox   matlab.ui.control.CheckBox
        PlotOptionsLabel                matlab.ui.control.Label
        SaveOptionsLabel                matlab.ui.control.Label
        NetworkTypeLabel                matlab.ui.control.Label
        NetworkSizeLabel                matlab.ui.control.Label
        SeedEditField                   matlab.ui.control.NumericEditField
        SeedEditFieldLabel              matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        KuhnSegmentLengthEditField      matlab.ui.control.NumericEditField
        KuhnSegmentLengthEditFieldLabel  matlab.ui.control.Label
        NumberofnetworksEditField       matlab.ui.control.NumericEditField
        NumberofnetworksEditFieldLabel  matlab.ui.control.Label
        DistributionTypeDropDown        matlab.ui.control.DropDown
        ManualSeedCheckBox              matlab.ui.control.CheckBox
        PolydisperseOptionsPanel        matlab.ui.container.Panel
        AssignmentModesLabel_2          matlab.ui.control.Label
        KuhnSegmentAssignmentModeDropDown  matlab.ui.control.DropDown
        KuhnSegmentAssignmentModeDropDownLabel  matlab.ui.control.Label
        BimodalOptionsPanel             matlab.ui.container.Panel
        AssignmentModesLabel            matlab.ui.control.Label
        KuhnSegmentsLabel               matlab.ui.control.Label
        DistributionHeightModeDropDown  matlab.ui.control.DropDown
        DistributionHeightModeDropDownLabel  matlab.ui.control.Label
        KuhnSegmentAssignementModeDropDown  matlab.ui.control.DropDown
        KuhnSegmentAssignementModeDropDownLabel  matlab.ui.control.Label
        BinWindowModeDropDown           matlab.ui.control.DropDown
        BinWindowModeDropDownLabel      matlab.ui.control.Label
        Type2FirstCheckBox              matlab.ui.control.CheckBox
        Type2DistributionWidthEditField  matlab.ui.control.NumericEditField
        Type2DistributionWidthEditFieldLabel  matlab.ui.control.Label
        Type1DistributionWidthEditField  matlab.ui.control.NumericEditField
        Type1DistributionWidthEditFieldLabel  matlab.ui.control.Label
        NumberofType2EditField          matlab.ui.control.NumericEditField
        NumberofType2EditFieldLabel     matlab.ui.control.Label
        Type2EditField                  matlab.ui.control.NumericEditField
        Type2EditFieldLabel             matlab.ui.control.Label
        Type1EditField                  matlab.ui.control.NumericEditField
        Type1EditFieldLabel             matlab.ui.control.Label
        GenerateNetworkButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NetworkObject
        logListener
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            %% Assign UI Values to the network properties
            % General Settings
            app.NetworkObject.dist_type = app.DistributionTypeDropDown.Value;
            app.NetworkObject.Nreplicates = app.NumberofnetworksEditField.Value;
            app.NetworkObject.b = app.KuhnSegmentLengthEditField.Value;
            app.NetworkObject.Lx = app.LengthEditField.Value;
            app.NetworkObject.Ly = app.HeightEditField.Value;
            app.NetworkObject.imanualseed = app.ManualSeedCheckBox.Value;
            app.NetworkObject.seed = app.SeedEditField.Value;
            
            app.NetworkObject.iplot = app.ShowNetworkStatisticsCheckBox.Value;
            app.NetworkObject.isave = app.SaveNetworksCheckBox.Value;
            app.NetworkObject.lammps_data_file = app.DataFileNameEditField.Value;
            app.NetworkObject.bond_table_file = app.TableFileNameEditField.Value;
            app.NetworkObject.iadvancedoptions = app.CheckBox.Value;

            % Polydisperse Settings
            app.NetworkObject.distribution_assignment_mode_poly = app.KuhnSegmentAssignmentModeDropDown.Value;
            
            % Bimodal Settings
            app.NetworkObject.N1 = app.Type1EditField.Value;
            app.NetworkObject.N2 = app.Type2EditField.Value;
            app.NetworkObject.bin_window_method = app.BinWindowModeDropDown.Value;
            app.NetworkObject.distribution_assignment_mode = app.KuhnSegmentAssignementModeDropDown.Value;
            app.NetworkObject.distribution_height_mode = app.DistributionHeightModeDropDown.Value;
            app.NetworkObject.long_first = app.Type2FirstCheckBox.Value;
            app.NetworkObject.P = app.NumberofType2EditField.Value; 
            app.NetworkObject.N2_bonds = app.NumberofType2EditField.Value;
            app.NetworkObject.dr1 = app.Type1DistributionWidthEditField.Value;
            app.NetworkObject.dr2 = app.Type2DistributionWidthEditField.Value;
            
            
            %% Send the network object to the generator
            [Domain,Atoms,Bonds,Nvec] = generateNetwork(app.NetworkObject);
            
            %% Unpack information and visualize results in the UI Window
            % extract data from atoms
            x = Atoms(:,2);
            y = Atoms(:,3);
            Total_bond = length(Bonds);
            
            scatter(app.UIAxes,x,y, 6, 'k', 'filled')
            hold(app.UIAxes,'on')
            for ii = 1:Total_bond
                i1 = Bonds(ii,2);
                i2 = Bonds(ii,3);
                if Bonds(ii,5) == 1
                    plot(app.UIAxes,[Atoms(i1,2) Atoms(i2,2)], [Atoms(i1,3) Atoms(i2,3)], 'k-');
                else
                    plot(app.UIAxes,[Atoms(i1,2) Atoms(i2,2)], [Atoms(i1,3) Atoms(i2,3)], 'r-');
                end
            end
            hold(app.UIAxes,'off')
    
        end

        function onLogEvent(app, src, ~)
            % If the log is updated, write to the log window.
            newLog = src.log;
            app.LogWindowTextArea.Value = newLog;
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %create network object
            app.NetworkObject = network;
            
            % Attach a PostSet listener to the observable `log` property so the callback
            % receives event data containing AffectedObject (ev.AffectedObject.log)
            app.logListener = addlistener(app.NetworkObject, 'logUpdated', @(src,ev) app.onLogEvent(src,ev));
        end

        % Button pushed function: GenerateNetworkButton
        function GenerateNetworkButtonPushed(app, event)
            
            % Need to do some checks if save option enabled
            if app.SaveNetworksCheckBox.Value
                
                sprintf('%s',app.EditField.Value);
                %if no locations and names are given, auto populate
                if isempty(app.EditField.Value)
                    app.EditField.Value = pwd;
                end
                if isempty(app.DataFileNameEditField.Value)
                    app.DataFileNameEditField.Value = 'Network_nano.dat';
                end
                if isempty(app.TableFileNameEditField.Value)
                    app.TableFileNameEditField.Value = 'bond.table';
                end

            end
            
            %
            updatePlot(app)
        end

        % Value changed function: DistributionTypeDropDown
        function DistributionTypeDropDownValueChanged(app, event)
            value = app.DistributionTypeDropDown.Value;
            
            % Toggle the visibility of option menus
            if strcmpi(value,'bimodal')
                app.BimodalOptionsPanel.Visible = "on";
                app.PolydisperseOptionsPanel.Visible = 'off';
            elseif strcmpi(value,'polydisperse')
                app.PolydisperseOptionsPanel.Visible = "on";
                app.BimodalOptionsPanel.Visible = 'off';
            else
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.NetworkObject.write_location = uigetdir;
            app.EditField.Value = app.NetworkObject.write_location;
        end

        % Value changed function: DistributionHeightModeDropDown
        function DistributionHeightModeDropDownValueChanged(app, event)
            value = app.DistributionHeightModeDropDown.Value;
            
            % Toggle the visibility of the fields
            if strcmpi(value,"fixed")
                app.NumberofType2EditFieldLabel.Text = "Number of Type 2";
            elseif strcmpi(value,'prob')
                app.NumberofType2EditFieldLabel.Text = 'Fraction of Type 2';
            else
            end
        end

        % Value changed function: BinWindowModeDropDown
        function BinWindowModeDropDownValueChanged(app, event)
            value = app.BinWindowModeDropDown.Value;
            
            if strcmpi(value,'manual')
                app.Type1DistributionWidthEditField.Editable = 'on';
                app.Type2DistributionWidthEditField.Editable = 'on';
            else
                app.Type1DistributionWidthEditField.Editable = 'off';
                app.Type2DistributionWidthEditField.Editable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.GenerateNetworkButton = uibutton(app.UIFigure, 'push');
            app.GenerateNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNetworkButtonPushed, true);
            app.GenerateNetworkButton.Text = 'Generate Network';
            app.BimodalOptionsPanel = uipanel(app.UIFigure);
            app.BimodalOptionsPanel.Title = 'Bimodal Options';
            app.Type1EditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.Type1EditFieldLabel.Text = 'Type 1';
            app.Type1EditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.Type1EditField.Value = 20;
            app.Type2EditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.Type2EditFieldLabel.Text = 'Type 2';
            app.Type2EditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.Type2EditField.Value = 50;
            app.NumberofType2EditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.NumberofType2EditFieldLabel.Text = 'Number of Type 2';
            app.NumberofType2EditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.Type1DistributionWidthEditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.Type1DistributionWidthEditFieldLabel.Text = 'Type 1 Distribution Width';
            app.Type1DistributionWidthEditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.Type1DistributionWidthEditField.Value = 10;
            app.Type2DistributionWidthEditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.Type2DistributionWidthEditFieldLabel.Text = 'Type 2 Distribution Width';
            app.Type2DistributionWidthEditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.Type2DistributionWidthEditField.Value = 10;
            app.Type2FirstCheckBox = uicheckbox(app.BimodalOptionsPanel);
            app.Type2FirstCheckBox.Text = 'Type 2 First';
            app.BinWindowModeDropDownLabel = uilabel(app.BimodalOptionsPanel);
            app.BinWindowModeDropDownLabel.Text = 'Bin Window Mode';
            app.BinWindowModeDropDown = uidropdown(app.BimodalOptionsPanel);
            app.BinWindowModeDropDown.Items = {'manual', 'adaptive'};
            app.BinWindowModeDropDown.ValueChangedFcn = createCallbackFcn(app, @BinWindowModeDropDownValueChanged, true);
            app.BinWindowModeDropDown.Value = 'manual';
            app.KuhnSegmentAssignementModeDropDownLabel = uilabel(app.BimodalOptionsPanel);
            app.KuhnSegmentAssignementModeDropDownLabel.Text = 'Kuhn Segment Assignement Mode';
            app.KuhnSegmentAssignementModeDropDown = uidropdown(app.BimodalOptionsPanel);
            app.KuhnSegmentAssignementModeDropDown.Items = {'single', 'geom', 'gaussian'};
            app.KuhnSegmentAssignementModeDropDown.Value = 'gaussian';
            app.DistributionHeightModeDropDownLabel = uilabel(app.BimodalOptionsPanel);
            app.DistributionHeightModeDropDownLabel.Text = 'Distribution Height Mode';
            app.DistributionHeightModeDropDown = uidropdown(app.BimodalOptionsPanel);
            app.DistributionHeightModeDropDown.Items = {'prob', 'fixed'};
            app.DistributionHeightModeDropDown.ValueChangedFcn = createCallbackFcn(app, @DistributionHeightModeDropDownValueChanged, true);
            app.DistributionHeightModeDropDown.Value = 'fixed';
            app.KuhnSegmentsLabel = uilabel(app.BimodalOptionsPanel);
            app.KuhnSegmentsLabel.Text = 'Kuhn Segments';
            app.AssignmentModesLabel = uilabel(app.BimodalOptionsPanel);
            app.AssignmentModesLabel.Text = 'Assignment Modes';
            app.PolydisperseOptionsPanel = uipanel(app.UIFigure);
            app.PolydisperseOptionsPanel.Title = 'Polydisperse Options';
            app.PolydisperseOptionsPanel.Visible = 'off';
            app.KuhnSegmentAssignmentModeDropDownLabel = uilabel(app.PolydisperseOptionsPanel);
            app.KuhnSegmentAssignmentModeDropDownLabel.Text = 'Kuhn Segment Assignment Mode';
            app.KuhnSegmentAssignmentModeDropDown = uidropdown(app.PolydisperseOptionsPanel);
            app.KuhnSegmentAssignmentModeDropDown.Items = {'geom', 'range', 'pmf'};
            app.KuhnSegmentAssignmentModeDropDown.Value = 'geom';
            app.AssignmentModesLabel_2 = uilabel(app.PolydisperseOptionsPanel);
            app.AssignmentModesLabel_2.Text = 'Assignment Modes';
            app.GeneralSettingsPanel = uipanel(app.UIFigure);
            app.GeneralSettingsPanel.Title = 'General Settings';
            app.ManualSeedCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.ManualSeedCheckBox.Text = 'Manual Seed';
            app.DistributionTypeDropDown = uidropdown(app.GeneralSettingsPanel);
            app.DistributionTypeDropDown.Items = {'polydisperse', 'bimodal'};
            app.DistributionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DistributionTypeDropDownValueChanged, true);
            app.DistributionTypeDropDown.Value = 'bimodal';
            app.NumberofnetworksEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.NumberofnetworksEditFieldLabel.Text = 'Number of networks';
            app.NumberofnetworksEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.NumberofnetworksEditField.Limits = [1 Inf];
            app.NumberofnetworksEditField.Value = 1;
            app.KuhnSegmentLengthEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.KuhnSegmentLengthEditFieldLabel.Text = 'Kuhn Segment Length';
            app.KuhnSegmentLengthEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.KuhnSegmentLengthEditField.Limits = [0 Inf];
            app.KuhnSegmentLengthEditField.Value = 1.6;
            app.LengthEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.LengthEditField.Value = 100;
            app.HeightEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.HeightEditField.Value = 100;
            app.SeedEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.NetworkSizeLabel = uilabel(app.GeneralSettingsPanel);
            app.NetworkSizeLabel.Text = 'Network Size';
            app.NetworkTypeLabel = uilabel(app.GeneralSettingsPanel);
            app.NetworkTypeLabel.Text = 'Network Type';
            app.SaveOptionsLabel = uilabel(app.GeneralSettingsPanel);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.PlotOptionsLabel = uilabel(app.GeneralSettingsPanel);
            app.PlotOptionsLabel.Text = 'Plot Options';
            app.ShowNetworkStatisticsCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.ShowNetworkStatisticsCheckBox.Text = 'Show Network Statistics';
            app.NetworkSeedLabel = uilabel(app.GeneralSettingsPanel);
            app.NetworkSeedLabel.Text = 'Network Seed';
            app.CheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.CheckBox.Text = '';
            app.AdvancedOptionsLabel = uilabel(app.GeneralSettingsPanel);
            app.AdvancedOptionsLabel.Text = 'Advanced Options';
            app.SelectFolderButton = uibutton(app.GeneralSettingsPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.SaveNetworksCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.SaveNetworksCheckBox.Text = 'Save Networks';
            app.EditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.EditField.Editable = 'off';
            app.DataFileNameEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.DataFileNameEditFieldLabel.Text = 'Data File Name';
            app.DataFileNameEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.TableFileNameEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.TableFileNameEditFieldLabel.Text = 'Table File Name';
            app.TableFileNameEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.NetworkStatisticsPanel = uipanel(app.UIFigure);
            app.NetworkStatisticsPanel.Title = 'Network Statistics';
            app.UIAxes2 = uiaxes(app.NetworkStatisticsPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.NetworkStatisticsPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.LogWindowTextAreaLabel = uilabel(app.UIFigure);
            app.LogWindowTextAreaLabel.Text = 'Log Window';
            app.LogWindowTextArea = uitextarea(app.UIFigure);
            app.LogWindowTextArea.Editable = 'off';
            app.AdvancedOptionsPanel = uipanel(app.UIFigure);
            app.AdvancedOptionsPanel.Title = 'Advanced Options';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NetworkGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end